classdef ResistorColourDecoderApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Band1DropDownLabel              matlab.ui.control.Label
        Band1DropDown                   matlab.ui.control.DropDown
        Band2DropDownLabel              matlab.ui.control.Label
        Band2DropDown                   matlab.ui.control.DropDown
        Band3DropDownLabel              matlab.ui.control.Label
        Band3DropDown                   matlab.ui.control.DropDown
        Band4DropDownLabel              matlab.ui.control.Label
        Band4DropDown                   matlab.ui.control.DropDown
        Band5DropDownLabel              matlab.ui.control.Label
        Band5DropDown                   matlab.ui.control.DropDown
        ResistorColourCodeDecoderLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        NOTEIfresistoronlyhas4bandsleaveBand3asEmptyLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        EditField_2                     matlab.ui.control.EditField
        EditField_3                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        EditField_5                     matlab.ui.control.EditField
        HelpButton                      matlab.ui.control.Button
        ResultsLabel                    matlab.ui.control.Label
        InputLabel                      matlab.ui.control.Label
        ResistorIllustrationLabel       matlab.ui.control.Label
        ForhelppleaseclickbelowLabel    matlab.ui.control.Label
        ResultText                      matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %convert drop down values to doubles
            band1 = str2double(app.Band1DropDown.Value);
            band2 = str2double(app.Band2DropDown.Value);
            if(app.Band3DropDown.Value ~= 'empty') 
                band3 = str2double(app.Band3DropDown.Value);
            else 
                band3 = app.Band3DropDown.Value;
            end
            band4 = str2double(app.Band4DropDown.Value);
            band5 = str2double(app.Band5DropDown.Value);
            
            %input values into function
            if (band3 == 'empty')
                [R,T,Tmin,Tmax,RStr] = ResistorFunction(band1,band2,band4,band5);
            else
                [R,T,Tmin,Tmax,RStr] = ResistorFunction(band1,band2,band3,band4,band5);
            end
            
            
            TextOutput = sprintf('This resistor has a resistance of %g%s Ω with a tolerance of %g %%.\nThis gives it a range of  %g%s Ω to %g%s Ω.',R,RStr,T,Tmin,RStr,Tmax,RStr); 
            
            %'print' final value
            %app.ResistanceEditField.Value = R;
            %app.ToleranceEditField.Value = T;
            %app.minRangeEditField.Value = Tmin;
            %app.maxRangeEditField.Value = Tmax;
            app.ResultText.Value = TextOutput
            %draw diagram
            app.EditField.BackgroundColor = Painter(app.Band1DropDown.Value, 'digits');
            app.EditField_2.BackgroundColor = Painter(app.Band2DropDown.Value, 'digits');
            
            if (app.Band3DropDown.Value ~= 'empty')
                app.EditField_3.BackgroundColor = Painter(app.Band3DropDown.Value, 'digits');
            else
                app.EditField_3.BackgroundColor = [0.94 0.94 0.94]
            end
            
            app.EditField_4.BackgroundColor = Painter(app.Band4DropDown.Value, 'multiplier');
            app.EditField_5.BackgroundColor = Painter(app.Band5DropDown.Value, 'tolerance');


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            Help;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Band1DropDownLabel = uilabel(app.UIFigure);
            app.Band1DropDownLabel.Text = 'Band 1';
            app.Band1DropDown = uidropdown(app.UIFigure);
            app.Band1DropDown.Items = {'Black', 'Brown', 'Red', 'Orange', 'Yellow', 'Green', 'Blue', 'Violet', 'Grey', 'White'};
            app.Band1DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Band1DropDown.Value = '0';
            app.Band2DropDownLabel = uilabel(app.UIFigure);
            app.Band2DropDownLabel.Text = 'Band 2';
            app.Band2DropDown = uidropdown(app.UIFigure);
            app.Band2DropDown.Items = {'Black', 'Brown', 'Red', 'Orange', 'Yellow', 'Green', 'Blue', 'Violet', 'Grey', 'White'};
            app.Band2DropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Band2DropDown.Value = '0';
            app.Band3DropDownLabel = uilabel(app.UIFigure);
            app.Band3DropDownLabel.Text = 'Band 3';
            app.Band3DropDown = uidropdown(app.UIFigure);
            app.Band3DropDown.Items = {'Empty', 'Black', 'Brown', 'Red', 'Orange', 'Yellow', 'Green', 'Blue', 'Violet', 'Grey', 'White'};
            app.Band3DropDown.ItemsData = {'empty', '0', '1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.Band3DropDown.Value = 'empty';
            app.Band4DropDownLabel = uilabel(app.UIFigure);
            app.Band4DropDownLabel.Text = 'Band 4';
            app.Band4DropDown = uidropdown(app.UIFigure);
            app.Band4DropDown.Items = {'Black', 'Brown', 'Red', 'Orange', 'Yellow', 'Green', 'Blue', 'Gold', 'Silver'};
            app.Band4DropDown.ItemsData = {'1', '10', '100', '1000', '10000', '100000', '1000000', '0.1', '0.01'};
            app.Band4DropDown.Value = '1';
            app.Band5DropDownLabel = uilabel(app.UIFigure);
            app.Band5DropDownLabel.Text = 'Band 5';
            app.Band5DropDown = uidropdown(app.UIFigure);
            app.Band5DropDown.Items = {'Brown', 'Red', 'Gold', 'Silver'};
            app.Band5DropDown.ItemsData = {'1', '2', '5', '10'};
            app.Band5DropDown.Value = '1';
            app.ResistorColourCodeDecoderLabel = uilabel(app.UIFigure);
            app.ResistorColourCodeDecoderLabel.Text = 'Resistor Colour Code Decoder';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.NOTEIfresistoronlyhas4bandsleaveBand3asEmptyLabel = uilabel(app.UIFigure);
            app.NOTEIfresistoronlyhas4bandsleaveBand3asEmptyLabel.Text = 'NOTE: If resistor only has 4 bands leave Band 3 as ''Empty''';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.HelpButton = uibutton(app.UIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.ResistorIllustrationLabel = uilabel(app.UIFigure);
            app.ResistorIllustrationLabel.Text = 'Resistor Illustration';
            app.ForhelppleaseclickbelowLabel = uilabel(app.UIFigure);
            app.ForhelppleaseclickbelowLabel.Text = 'For help please click below';
            app.ResultText = uitextarea(app.UIFigure);
            app.ResultText.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ResistorColourDecoderApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end